classdef HPLCapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HPLC                            matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DefineinputsTab                 matlab.ui.container.Tab
        ParamfilenameEditField          matlab.ui.control.EditField
        ParamfilenameEditFieldLabel     matlab.ui.control.Label
        PeakgroupEditField              matlab.ui.control.NumericEditField
        PeakgroupEditFieldLabel         matlab.ui.control.Label
        SlopethreshEditField            matlab.ui.control.NumericEditField
        SlopethreshEditFieldLabel       matlab.ui.control.Label
        SmoothwidthEditField            matlab.ui.control.NumericEditField
        SmoothwidthEditFieldLabel       matlab.ui.control.Label
        AmpthreshEditField              matlab.ui.control.NumericEditField
        AmpthreshEditFieldLabel         matlab.ui.control.Label
        CreateworklistcsvButton         matlab.ui.control.Button
        WaitmethodfolderlocationButton  matlab.ui.control.Button
        WaitMethodFolderLocationEditField  matlab.ui.control.EditField
        MethodtemplatefolderlocationButton  matlab.ui.control.Button
        MethodTemplateFolderLocationEditField  matlab.ui.control.EditField
        ResultsfilenameButton           matlab.ui.control.Button
        ResultsFileNameEditField        matlab.ui.control.EditField
        ResultsfolderlocationButton     matlab.ui.control.Button
        ResultsFolderLocationEditField  matlab.ui.control.EditField
        ExportDatatocsvButton           matlab.ui.control.Button
        VoidtimecutoffEditField         matlab.ui.control.NumericEditField
        VoidtimecuttoffminutesLabel     matlab.ui.control.Label
        Typically2n1wherennumberofvarsbeingoptimisedLabel  matlab.ui.control.Label
        FinalholdtimeminEditField       matlab.ui.control.EditField
        FinalholdtimeminEditFieldLabel  matlab.ui.control.Label
        InjectionvolumeLEditField       matlab.ui.control.EditField
        InjectionvolumeLEditFieldLabel  matlab.ui.control.Label
        SavechangesButton               matlab.ui.control.Button
        WaitMethodnameButton            matlab.ui.control.Button
        WaitMethodTemplateNameEditField  matlab.ui.control.EditField
        MethodtemplatenameButton        matlab.ui.control.Button
        MethodTemplateNameEditField     matlab.ui.control.EditField
        StepsLabel                      matlab.ui.control.Label
        AreacutoffEditField             matlab.ui.control.NumericEditField
        AreacutoffLabel                 matlab.ui.control.Label
        GeneratemethodfilesButton       matlab.ui.control.Button
        Step1Label_2                    matlab.ui.control.Label
        AmplituecutoffEditField         matlab.ui.control.NumericEditField
        AmplituecutoffLabel             matlab.ui.control.Label
        ReprocessfilesButton            matlab.ui.control.Button
        OptexpSpinner                   matlab.ui.control.Spinner
        NumberofexperimentsSpinnerLabel  matlab.ui.control.Label
        PauseInitButton                 matlab.ui.control.Button
        InitLamp                        matlab.ui.control.Lamp
        StartInitButton                 matlab.ui.control.Button
        Step2Label                      matlab.ui.control.Label
        InfomationTextArea              matlab.ui.control.TextArea
        SamplePositionEditField         matlab.ui.control.EditField
        SamplePositionEditFieldLabel    matlab.ui.control.Label
        OptimsationfilenameEditField    matlab.ui.control.EditField
        OptimsationfilenameEditFieldLabel  matlab.ui.control.Label
        EqulibrationtimeminSpinner      matlab.ui.control.Spinner
        EqulibrationtimeminSpinnerLabel  matlab.ui.control.Label
        AlgorithmDropDown               matlab.ui.control.DropDown
        AlgorithmDropDownLabel          matlab.ui.control.Label
        ObjectivefunctionsListBox       matlab.ui.control.ListBox
        ObjectivefunctionsListBoxLabel  matlab.ui.control.Label
        InitexpSpinner                  matlab.ui.control.Spinner
        NumberofinitalexperimentsSpinnerLabel  matlab.ui.control.Label
        FilesavelocationButton          matlab.ui.control.Button
        ConfigvarButton                 matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        LoadVarsButton                  matlab.ui.control.Button
        ApplyVarsButton                 matlab.ui.control.Button
        FileSaveLocatonEditField        matlab.ui.control.EditField
        Step1Label                      matlab.ui.control.Label
        DatavisulisationTab             matlab.ui.container.Tab
        DataTransformCheckBox           matlab.ui.control.CheckBox
        DotsizeSlider                   matlab.ui.control.Slider
        DotsizeSliderLabel              matlab.ui.control.Label
        ColourbarDropDown               matlab.ui.control.DropDown
        ColourbarLabel                  matlab.ui.control.Label
        TimemaxSpinner                  matlab.ui.control.Spinner
        TimemaxSpinnerLabel             matlab.ui.control.Label
        YLowerSpinner                   matlab.ui.control.Spinner
        YlowerSpinnerLabel              matlab.ui.control.Label
        YUpperSpinner                   matlab.ui.control.Spinner
        YUpperSpinnerLabel              matlab.ui.control.Label
        VarListBox                      matlab.ui.control.ListBox
        ToggledebugCheckBox             matlab.ui.control.CheckBox
        Switchgraphstate                matlab.ui.control.Switch
        HPLCListBox                     matlab.ui.control.ListBox
        GraphAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        variables = []; % Contains the table with the differnt vairables that define the optimisation
        totExpNumb = 0; % The number of experiments
        initExpNumb = 0; % The number of intial experiments
        equalTime = 0; % The equlibration time between each run
        MethodFolderLocation = string; % Where the data files are stored on the network
        LocalFolderLocation = string; % String of the local address that is used to find the files on the mass hunter computer
        inputVarNames = string; % Names of each input var
        allConditions = []; % chromatogram of inital conditons defined by the latin hypercube function
        AllResults = []; % Inital results that are stored
        currExp = 1; % The current experiment that is being investigated
        responceData = []; % A table that contains the responce data, depending on the ones selected
        varFileName = ""; % Name of the file that is loaded or gets saved containing all data
        allData = cell(1,2) % The cell that gets saving containing all the data
        graphPeaksData = cell(1,2); % Contains the x and y data as well as the peaks data for each file
        paused = false; % Sets the paused state
        samplePos = ""; % The sample position
        allVars = []; % All the variables that have been selected + the responce vars selected
        varOrder = []; % String list that contains the variables to be displayed in order in the desgin space
        algOutput = cell(1,2); % Cell that contains all the output of the opt function used
        ResultsDir = "Results\" % The folder that contains all the results
        logTrack; % Log file class that keeps track of everything that has happened
        logFileName = "logFile.txt" % The name that each log file gets saved as
        folderSave = ""; % The current file location of the saved data
        fileNameSave = "" % Name of the file being saved
        ampCutoff; % Amplitude cut off value for ignoring peaks below a certian amplitude
        areaCutoff; % Area cut off value for ignoring peaks below a certain area
        HPLCDataFileName = "HPLCData";
        MethodsFileName = "Methods";
        methodName = "Method"
        dataFileName = "Data"
        optimisationLoop = false;
        waitForResults = false;
        voidTimeCutOff;
        wavelengthSelected;
        bandwidthSelected;
        colourMap;
        colourMapsAll = string;
        dotSize;
        ResultsFileName;
        ResultsFolderLocation;
        WaitMethodTemplateName;
        MethodTemplateName;
        MethodTemplateFolderLocation;
        WaitMethodFolderLocation;

        finalHoldTime;
        injVol;

        ampThresh;
        slopeThresh;
        peakGroup;
        smoothWidth;
        paramFileName;
        
        Settings; % Structure containing all the different setting values for the optimisation.

        chromatogramCounter = 1; % The current chromatogram number being analysied
        chromatogramNames = string; % All the chromatogram names used for the list box items
        chromatogramLable = string; % The displayed chromatogram name for the list box items
        currentWavelength = 3; % Current wavelength displayed on the graph
        chromatogramNumbShown = 1; % Current chromatogram number shown on the graph
        
        textArea = string; % The current string displayed in the list box
        
        algorithms = cell(2,1)% Named list of the algorithms that can be selected
        algSelected = []; % The algorithm actually selected to run
        responceFunctions = cell(2,1) % Named list of the responce function and actual function attached to it
        respSelected = []; % The responce functions actually selected to run
        
        wongCM; %colour map for colour blind pallette
        
    end
    
    properties (Access = private)
        DialogApp % Description
    end
    
    methods (Access = private)

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        
        function updateTextBox(app, append, text, log)
        if nargin == 3
            log = false;
        end
            
        % If append == true, then the text is appended to the already
        % current text and displayed in the message box. If false, then it
        % writes over all prevous messages displayed in the box.
        
            if append == true
                app.textArea = app.textArea + text;
            else
                app.textArea = text;
            end
            
            app.InfomationTextArea.Value = app.textArea;
            
            if log == true
                app.logTrack.info('updateTextBox', text)
            end
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        
        function resetUI(app)
            
            % Disable the buttons before variables and inital experiment
            % condtions are 'applied'.
            app.HPLCListBox.Items = {};
            cla(app.GraphAxes,'reset');
            app.VarListBox.Items = {};
            
            % Wipe the text area
            app.InfomationTextArea.Value = "";
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        
        function updateRespBox(app)
            

            % Set the option in the responce functions list to the first
            % option just to reset it.
            app.ObjectivefunctionsListBox.Value = app.responceFunctions{1,:}(1);
            % Finds out the algorithm that is slected
            respFunc = app.AlgorithmDropDown.Value;
            listAlgs = cellfun(@string, app.AlgorithmDropDown.Items);
            
            if respFunc == listAlgs(1)
                % TS-EMO
                % Enable the multiselect as it is a multi-objective
                % optimisation algorithm
                app.ObjectivefunctionsListBox.Multiselect = true;
            elseif respFunc == listAlgs(2)
                % BaysiainOptimiser
                % Enable the multiselect as it is a multi-objective
                % optimisation algorithm
                app.ObjectivefunctionsListBox.Multiselect = false;    
            end
            
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        
        function applyVars(app)
            % Obtains all the data and puts it into a single cell, which is
            % then read and displayed in the message box
            try
                % Create log file
                app.logTrack = log4m.getLogger(app.folderSave + "\" + app.logFileName);

                % Opens the variables file and reads the data. This data is in
                % a struct format so convert to cell and then read the first
                % (and only) entry.
                app.variables = load("Variables.mat");
                app.variables = struct2cell(app.variables);
                app.variables = app.variables{1,1};
                
                %Save the current settings
                app.saveSettings();

                % Copy over template files and create "Methods" folder.
                mkdir(app.folderSave + "\" + app.MethodsFileName);
                % Copy from the place where the user specifyed the template
                % to the new methods folder location. Rename the file to
                % "Template"
                FNTemp = app.MethodTemplateFolderLocation + "\" + app.MethodTemplateName;
                FNMeth = app.folderSave + "\" + app.MethodsFileName + "\" + "Template";

                % Copy files from template
                copyfile(FNTemp + ".acm", FNMeth + ".acm");
                copyfile(FNTemp + ".w2200", FNMeth + ".w2200");
                copyfile(FNTemp + ".ccm", FNMeth + ".ccm");
                copyfile(FNTemp + ".bgm", FNMeth + ".bgm");
                copyfile(FNTemp + ".wpda", FNMeth + ".wpda");
                copyfile(FNTemp + ".exp", FNMeth + ".exp");
        
                % Now copy over the wait file the user wants to use
                FNTemp2 = app.WaitMethodFolderLocation + "\" + app.WaitMethodTemplateName;
                FNMeth2 = app.folderSave + "\" + app.MethodsFileName + "\" + "WaitMethod";

                % Copy files from template
                copyfile(FNTemp2 + ".acm", FNMeth2 + ".acm");
                copyfile(FNTemp2 + ".w2200", FNMeth2 + ".w2200");
                copyfile(FNTemp2 + ".ccm", FNMeth2 + ".ccm");
                copyfile(FNTemp2 + ".bgm", FNMeth2 + ".bgm");
                copyfile(FNTemp2 + ".wpda", FNMeth2 + ".wpda");
                copyfile(FNTemp2 + ".exp", FNMeth2 + ".exp");

                % Change settings of template files to new copied ones
                app.MethodTemplateFolderLocationEditField.Value = app.folderSave + "\" + app.MethodsFileName;
                app.WaitMethodFolderLocationEditField.Value = app.folderSave + "\" + app.MethodsFileName;
                app.MethodTemplateNameEditField.Value = "Template";
                app.WaitMethodTemplateNameEditField.Value = "WaitMethod";

                %Save the current settings
                app.saveSettings();

                app.respSelected = app.ObjectivefunctionsListBox.Value;
                % If there are multiple responce functions selected, it turns
                % them into a cell. Therefore this detects this and reverts it
                % back to a strign array if multiple are selected. If only one
                % is detected it is a char. Therefore convert it to a string.
                if iscell(app.respSelected)
                    app.respSelected = cellfun(@string, app.respSelected);
                else
                    app.respSelected = string(app.respSelected);
                end
                
                % Extract the optimsied variables and calucalte the inital
                % condtions
                
                % Extacts the data from each row in the variables table
                names = app.variables.Name';
                dorc = app.variables.DiscreteOrContinuous';
                opt =  app.variables.Optimise';
                data = app.variables.Data';
                
                % Builds the inital condtions based on the a Latin hypercube
                % sampling desgin and changes the upper and lower bounds based
                % on the values set
                tempData = zeros(app.initExpNumb, length(names));
                X = lhsdesign(app.initExpNumb,nnz(opt));  % Latin hypercube design
                counter = 1;
                for k = 1:length(names)
                    
                    % If the value is being optimised and is continous var
                    if opt(k) == 1 && dorc(k) == "C"
                        % Set value based on the upper and lower bounf
                        tempData(:,k) = round(X(:,counter).*(data{1,k}(2)-data{1,k}(1))+data{1,k}(1),2);        % adjustment of bounds
                        counter = counter + 1;
                        
                    % If the value is not being optimised and is a continous
                    % var
                    elseif opt(k) == 0 && dorc(k) == "C"
                        tempData(:,k) = data{1,k}(1);
                        
                    % If the value is discrete and is being optimise
                    % COMMENT
                    elseif opt(k) == 1 && dorc(k) == "D"
                        index = round(X(:,counter)*(length(data{k})-1),0)+1;
                        extendedData = data{k};
                        tempData(:,k) = extendedData(:,index)';
                        counter = counter + 1;
                        
                    % If the value is discrete and is not being optimised -
                    % currently just sets it to one value 
                    elseif opt(k) == 0 && dorc(k) == "D"
                        tempData(:,k) = str2num(data{1,k}(1));
                    end
                end
                
                % Converts all initial condtions into a table format
                app.allConditions = array2table(tempData, "VariableNames", names);
    
                app.InitLamp.Color = [1 0 0]; %Turn inital condtions lamp to red (not running)

                % Resets the current experiment back to 1;
                app.currExp = 1;
                
                % Setup inital results table
                app.AllResults = cell2table(cell(1, length(app.respSelected)), "VariableNames",app.respSelected);
                
                % Set up the Var list box with all the variables
                app.allVars = [names app.respSelected];
                app.VarListBox.Items = app.allVars;
                
                % Reset the graph peaks data and algorithm output (wiping the prevous save)
                app.graphPeaksData = cell(1,2);
                app.algOutput = cell(1,2);

    
                % The function that displays the data submited in the text box
                app.DisplayData(true);
    
                % Save var file given condtions
                app.saveVarFile()
                
                app.logTrack.debug('applyVars', 'Variables successfuly initialised, saved and log file created.')
            catch
                app.logTrack.error('applyVars', 'Variables failed to initialise/save.')
                app.updateTextBox(true, "Variables failed to initialise/save.")
            end

        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        
        function saveVarFile(app)

            % Set all the variables that have been saved.
            app.allData{1,1} = "Variables";
            app.allData{1,2} = app.variables;
            
            app.allData{2,1} = "AllConditions";
            app.allData{2,2} = app.allConditions;
            
            app.allData{3,1} = "AllResults";
            app.allData{3,2} = app.AllResults;
            
            app.allData{4,1} = "AlgSelected";
            app.allData{4,2} = app.algSelected;
            
            app.allData{5,1} = "InitExpNumb";
            app.allData{5,2} = app.initExpNumb;
            
            app.allData{6,1} = "Data";
            app.allData{6,2} = app.graphPeaksData;
            
            app.allData{7,1} = "AlgorithmOutput";
            app.allData{7,2} = app.algOutput;

            app.allData{8,1} = "Settings";
            app.allData{8,2} = app.Settings;
            

            % Saves this data to a temp variables
            data = app.allData;
            % Creates the new folder. If the directory already exists it
            % does not overwrite it.
            mkdir(app.folderSave)
            
            % Saves the data to ".mat" file. Overwrites it if it already
            % exists.
            save(app.folderSave + "\" + app.varFileName + ".mat" ,"data");


        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function DisplayData(app,logData)
            if nargin == 1
                logData = false;
            end
            
            % Notfiys user that the variables have been loaded and wipes
            % prevous textbox data
            app.updateTextBox(true, "Variables loaded!" + newline + newline, logData)
            
            %***WRITE VARIABLES AND CONDTIONS***
            
            app.updateTextBox(true, "Conditions:" + newline + newline,logData)
            
            % Converts the variables table into a cell matrix of characters:
            % this is the format it needs to be in for the 'print_table'
            % function. "'UniformOutput', false" is needed to do this.
            stringVars = cellfun(@string, table2cell(app.variables), 'UniformOutput', false);
            
            % If an UB and LB, or multiple descrete variables are selected,
            % they need to be converted into one char cell instead of
            % multiple. This code finds the cell sizes where the length is 
            % >1 and then joins them with commas between each value.
            sizes = cell2mat(cellfun(@length, stringVars, 'UniformOutput', false));
            stringVars(sizes>1) = cellfun(@(x) strjoin(x, ", "), stringVars(sizes>1), 'UniformOutput', false);
            
            % Obtains the names of the headers for the top of the table
            headers = app.variables.Properties.VariableNames;
            
            % Generates the neatly formated table for display. Needs to be
            % converted from char to string as the newline command wont
            % work with this and will display incorrectly in the log file.
            tableText = newline + string(print_table(stringVars, {'%s'}, headers,'printBorder',1));
            
            % Updates the textbox with the formated data
            app.updateTextBox(true, tableText,logData);
            
            %Spaces between the next outputs
            app.updateTextBox(true, " " + newline + newline,logData);
            
            %***WRITE SETTINGS AND ALGORITHM CONDTIONS***
            
            % Displays the other variables set by the user
            app.updateTextBox(true, "Inital number of experiments: " + string(app.initExpNumb) + newline,logData)
            app.updateTextBox(true, "Equilbration time: " + string(app.equalTime) + newline,logData)
            app.updateTextBox(true, "Sample Postion: " + app.SamplePositionEditField.Value + newline,logData)
            % Updates the user what the responce functions selected are
            app.updateTextBox(true, "Optimisation algorithm selected: " + app.algSelected + newline,logData);
            app.updateTextBox(true, "Responce functions: " + join(app.respSelected, ', ') + newline,logData);
           
            %***WRITE CURRENT EXPERIMENTS AND RESULTS***
            
            % Prints the current optimisation condtions in the text box.
            % Requires a monospaced font to be selected in the text box.      
            app.updateTextBox(true, newline + "Experiments: " + newline + newline,logData);
            
            % Writes all the experiments currently run
            app.writeCurrExperiment(1:size(app.allConditions,1), logData);
                
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function UpdateGraphList(app)
            
            % If the first element is empty, assuming that the file has
            % been saved but no runs have been done, make the list box
            % empty. Otherwise update the list box with all the names of
            % the HPLC file names that have been read.
            if isempty(app.graphPeaksData{1,1})
                % No data detected so set the names and labels blank
                app.chromatogramNames = {};
                app.chromatogramLable = {};
                
                % Update the list box
                app.HPLCListBox.Items = app.chromatogramLable;
                
            else
                % Extract all the chromatogram names and turn them from a
                % cell array into a string array
                app.chromatogramNames = cellfun(@string, app.graphPeaksData(:,1));
                % Make a copy that is ready to modify.
                app.chromatogramLable = app.chromatogramNames;
                
                % Append a number in frount of each chromatogram name to
                % display what experiment it is
                for label = 1:length(app.chromatogramLable)
                    app.chromatogramLable(label) = string(label) + ". " + app.chromatogramLable(label);
                end
                
                % Update the list box with the chromatrogram 'labels'
                app.HPLCListBox.Items = app.chromatogramLable;
                
                % Selects the last (most recent) element in the list box
                % and displays it.
                app.HPLCListBox.Value = app.chromatogramLable(end);

                % Update the graph
                app.updateGraph()
                
            end
            
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
    function CalcResponces(app, newVar)
            
            % Gives logical array of each responce function that has been
            % selected in the list of aviable responces functions. This is
            % used to point at the function handle, which then evaluates
            % the function given the condtions to yeild the responce
            % function values in each one chosen.
            
            %Creates an intal empty logal array with all zero.
            logicArry = false(1,length(app.responceFunctions{1,:}));
           
            %Loops through each responce function selectd and finds the
            %location of the responce function in the list of the allowed
            %functions. Then adds this onto the logcal array.
            for resp = 1:length(app.respSelected)
                % Finds the location of the responce function in the list 
                % of the allowed functions
                temp = strcmp(app.responceFunctions{1,:}, app.respSelected(resp));
                
                % Adds on this location. If == 1 then that responce
                % function is being used, if == 0 then it is not.
                logicArry = logicArry + temp;
            end
            
            logicArry = logical(logicArry);

            % Delete the table and recreate it with the new headings.
            app.AllResults = [];
            app.AllResults = cell2table(cell(1, nnz(logicArry)), "VariableNames",app.responceFunctions{1,:}(logicArry));

            % Set the numbr of variables being looked at initally to 1
            numbVars = 1;
            %Loops through each logic (1 or 0) in the list of allowed
            %responce vars.
            for respFunc = 1:length(logicArry)
                % If == 1 this means it has been a selected as a responce
                % function to look at, so point to the function handle at
                % the same index but next cell and evaluate the function
                % given the inputs.
                if logicArry(respFunc) == 1
                    % This reprocesses all the data just in case there is a
                    % responce function that changes the other responce
                    % factors based on new data.
                    

                    %If running an opt newVar should be true so it
                    %processes the new experiment, otherwise set to false
                    %so it only processes experiments that have been run.
                    if newVar == true
                        expsToProcess = app.currExp;
                    else
                        expsToProcess = app.currExp - 1;
                    end

                    for x = 1:expsToProcess

                            %Read the experiment peaks data
                            %peaks = readSFCFilePeaks(app.ResultsFileName, x, ...
                            %app.areaCutoff, app.ampCutoff, app.voidTimeCutOff);    

                            %Read the time intensity data (chromatogram)
                            chromatogram = readSFCFileChrome(app.ResultsFileName, x);
                            
                            %(x,y,SlopeThreshold,AmpThreshold,smoothwidth,peakgroup,smoothtype)
                            %peaks = findpeaksG(chromatogram(:,1),chromatogram(:,2),100,1000,15,10,3);
                            peaks = findpeaksG(chromatogram(:,1),chromatogram(:,2),app.slopeThresh,app.ampThresh,app.smoothWidth,app.peakGroup,3);
                            allowedPeaks = peaks(:,2) < app.voidTimeCutOff & peaks(:,3) < app.ampCutoff & peaks(:,5) < app.areaCutoff;
                            peaks(allowedPeaks, :) = [];                                

                            %Extract the function handle based on the index
                            funcHandle = app.responceFunctions{2,1}{respFunc};
                            
                            %Evaluate the function and set the data in the results
                            %in the column that depends on numbVars so it is in the
                            %correct column.
                            app.AllResults{x,numbVars} = {feval(funcHandle, peaks, app.voidTimeCutOff, 1)};
                            app.graphPeaksData{x, 1} = app.dataFileName + string(x);
                            app.graphPeaksData{x, 2} = chromatogram;
                            app.graphPeaksData{x, 3} = peaks;
                        
                    end
                    % Increment the number of vars by one if there are more
                    % functions to be evaluated
                    numbVars = numbVars + 1;
                end
            end
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function updateGraph(app)
            %Updates the graph UI depending on the chromatogram selected
            %and the mode selescted.
            
            % Gets the selected value that is contained in the list box
            selectedGraphNames = app.HPLCListBox.Value;
            
            % If there are multiple selected, it turns
            % them into a cell. Therefore this detects this and reverts it
            % back to a strign array if multiple are selected. If only one
            % is detected it is a char. Therefore convert it to a string.
            if iscell(selectedGraphNames)
                selectedGraphNames = cellfun(@string, selectedGraphNames);
            else
                selectedGraphNames = string(selectedGraphNames);
            end
        
            %Creates an intal empty logal array with all zero with all the
            %possible selections it could be
            logicArry = false(1,length(app.HPLCListBox.Items));
            
            %Loops through each responce function selectd and finds the
            %location of the responce function in the list of the allowed
            %functions. Then adds this onto the logcal array.
            for resp = 1:length(selectedGraphNames)
                
                % Finds the location of the responce function in the list 
                % of the allowed functions and flips it so it adds
                % correctly to the logic array.
                temp = strcmp(app.chromatogramLable, selectedGraphNames(resp))';
                
                % Adds on this location. If == 1 then that responce
                % function is being used, if == 0 then it is not.
                logicArry = logicArry + temp;
            end
            
            % Clear graph
            cla(app.GraphAxes,'reset')
            
            % If the 'Graph state' is in the chromatogram state
            if app.Switchgraphstate.Value == "1"
                
                % Reset var order when it is swtiched back and disable the
                % use of the var box
                app.varOrder = [];
                
                % Start overlaying
                hold (app.GraphAxes, 'on');
                
                % loop through each element in the logical array and if it
                % is equal to one, it has been selected so plot it
                currGraph = 0;
                for ind = 1:length(logicArry)
                    if logicArry(ind) == 1
                            
                        currGraph = currGraph + 1;

                        % Extracts the graph x and y data
                        x = app.graphPeaksData{ind,2}(:,1); % Time data
                        y = app.graphPeaksData{ind,2}(:,2); % Y coords of graph
                        
                        % Locations of each peak. peaks(x,1) is the peak number,
                        % peaks(x,2) is the x pos, peaks(x,3) is the y pos, peaks(x,4)
                        % is the full width and peaks(x,5) is the area, where x is the
                        % peak number.
                        peaks = app.graphPeaksData{ind,3};
                       
                        plot(app.GraphAxes, x, y + 10*(currGraph-1), "LineWidth", 1.5, "Color",app.wongCM(currGraph,:))         
                        %plot(app.GraphAxes, x, y, "LineWidth", 1.5, "Color",app.wongCM(currGraph,:))
                        
                        xlabel(app.GraphAxes, "Time / minutes", "FontSize",18,"FontWeight","bold")
                        ylabel(app.GraphAxes, "Intensity", "FontSize",18,"FontWeight","bold")
                        xlim(app.GraphAxes,[0 app.TimemaxSpinner.Value])
                        ylim(app.GraphAxes,[app.YLowerSpinner.Value app.YUpperSpinner.Value])

    
                        % Only if debug mode is switched on
                        if app.ToggledebugCheckBox.Value == 1
                            % Plot the 'tip' of each peak with a blue star
                            scatter(app.GraphAxes, peaks(:,2), peaks(:,3), "b*")
                            
                            % Plot the gaussian peaks based on the peaks data obtained.
                            % Loops through each peak in peaks array.
                            for currpeak = 1:size(peaks,1)
                                % Generate a uniformly spaced array of x values based
                                % on 4*width either side of the mean of the gaus
                                % function
                                gausX = linspace(peaks(currpeak,2) - (peaks(currpeak,4)*4), peaks(currpeak,2) + (peaks(currpeak,4)*4), 1000);
                                

                                gaus = @(x,mu,sig,amp,vo)amp*exp(-(((x-mu).^2)/(2*sig.^2)))+vo;

                                % Use matlabs built in function to generate the gaus
                                % plot, where gausX is the x coords, peaks(currpeak,4)/2.355
                                % is the standard deviation (FWHM/2.355) and peaks(currpeak,2)
                                % is the mean. peaks(currpeak,3) normalises the data to
                                % the correct height.
                                gausY = gaus(gausX, peaks(currpeak,2), peaks(currpeak,4)/2.355, peaks(currpeak,3), 0);
                                
                                % Plot this gaus function and then repeat for all the
                                % other peaks in the peaks array.
                                plot(app.GraphAxes, gausX, gausY, "r--")
                                
                                % Puts the label number above the peak
                                text(app.GraphAxes, peaks(currpeak,2), peaks(currpeak,3),string(peaks(currpeak,1)))
                            end
                        end      
                    end
                end
                
                % Stop overlaying
                hold (app.GraphAxes, 'off')
                
                % If there are greater than 2 chromoatograms being
                % displayed then display the legend
                if nnz(logicArry) >=2
                    % Selectedgraphnames is ordered the order it is clicked
                    % in. Therefore if 2 is clicked and then 1, the labels
                    % will be displayed in the order 2. and then 1., but
                    % the plotted graphs are displayed in numerical order
                    % 1. and then 2.. Therefore these selected graph names
                    % need to be sorted by numerical order.
                    
                    % Function by Stephen Cobeldick that sorts file names
                    % in order. Needs to be a cell of strings to perform
                    % the fucntion, then coverts back and sets the 'ordered' 
                    % names.
                    orderedNames = natsortfiles(cellstr(selectedGraphNames));
                    orderedNames = cellfun(@string, orderedNames);
                    %legend(app.GraphAxes,orderedNames);
                    legend(app.GraphAxes,orderedNames,"FontSize",18, "FontWeight","bold");
                end

            % If the graph state is in the 'desgin space' setting
            else
                
                
                % Gets the selected value that is contained in the list box
                selectedVars = app.VarListBox.Value;
                
                % If there are multiple selected, it turns
                % them into a cell. Therefore this detects this and reverts it
                % back to a strign array if multiple are selected. If only one
                % is detected it is a char. Therefore convert it to a string.
                if iscell(selectedVars)
                    app.varOrder = cellfun(@string, app.VarListBox.Value);
                else
                    app.varOrder = string(app.VarListBox.Value);
                end
                 
                % If more than 1 variable is selected (so a 2D or more plot
                % can be done)
                if length(selectedVars) > 1
                    % If too many variables are selected and are added to varOrder
                    % then only select the first 5 (plot will only go as
                    % high as the 5th dimension)
                    if length(app.varOrder) > 5
                        app.varOrder = app.varOrder(1:5);
                    end
                    
                    % Combines the inital condtions data with the objective
                    % function results data (AllResults). To make this work the inital
                    % condtions table is reduced to the same number of
                    % rows as the init results. As the elements in
                    % AllResults are each stored as a cell, a cell2table
                    % function is used to convert the values to doubles,
                    % and the table is recreated.
                    combinedData = [app.allConditions(1:size(app.AllResults,1),:), cell2table(app.AllResults{:,:}, "VariableNames", app.AllResults.Properties.VariableNames)];
                    
                    % Logic array contains the true false data of each
                    % graph that is selected. Where 0 = not seleected and 1
                    % = selectd. This function converts this into a
                    % numbered array and removes any 0's so that it can be
                    % used to index the combined data table. For example if
                    % logicArry = [0 0 1 0 1 0] then it would be this .* [1
                    % 2 3 4 5 6] = [0 0 3 0 5 0] and then zeros removed to
                    % give [3 5] so the table is indexed at 3 and 5.
                    logicArry = logicArry.*(1:1:length(logicArry));
                    logicArry(logicArry==0) = [];
                    logicArry = logicArry'; % Makes sure it is in the right direction
                    
                    % Uses this logical array to index the specfic data
                    extractedData = combinedData(logicArry, app.varOrder);

                    % Transform the data if wanted based on what has been
                    % done to it
                    if app.DataTransformCheckBox.Value == true

                        % Loop through each bit of extracted data
                        for eachDataSet = 1:size(app.responceFunctions{1,:},2)
                            
                            % If the extracted data is a response var that
                            % has been transformed, then revert it back to
                            % the normal value
                            foundHits = app.varOrder == app.responceFunctions{1,:}(eachDataSet) ;
                            if nnz(foundHits) > 0
                                varIndex = find(foundHits);
                                dataTransform = app.responceFunctions{3,1}(eachDataSet);
                                if dataTransform == "negNatLog"
                                    extractedData{:,varIndex} = exp(-extractedData{:,varIndex});
                                elseif dataTransform == "natLog"
                                    extractedData{:,varIndex} = exp(extractedData{:,varIndex});
                                elseif dataTransform == "neg"
                                    extractedData{:,varIndex} = -extractedData{:,varIndex};
                                elseif dataTransform == "none"
                                    extractedData{:,varIndex} = extractedData{:,varIndex};
                                end
                            end
                        end
                    end


                    % Plots a 2D graph when the number of columns in
                    % extracted data = 2;
                    if size(extractedData,2) == 2

                        % 2D Scatter graph
                        scatter(app.GraphAxes, extractedData{:,1},extractedData{:,2}, app.dotSize, 'black', 'filled')
                        
                        % As extractedData is a table it selects the VariableNames
                        % as the x and y label.
                        xlabel(app.GraphAxes, extractedData.Properties.VariableNames(1));
                        ylabel(app.GraphAxes, extractedData.Properties.VariableNames(2));
                        
                        % If debug mode is on, display the text
                        if app.ToggledebugCheckBox.Value == 1
                            text(app.GraphAxes, extractedData{:,1},extractedData{:,2}, " " + string(logicArry))
                        end
                        
                    % Plots a 3D graph when the number of columns in
                    % extracted data = 3;    
                    elseif size(extractedData,2) == 3
                        
                        % 3D scatter graph
                        scatter3(app.GraphAxes, extractedData{:,1}, extractedData{:,2}, extractedData{:,3}, app.dotSize, 'black', 'filled')
                        
                        grid(app.GraphAxes, "on")
                        
                        % Add to the same axis the lines that go down to
                        % the xy plane
                        hold (app.GraphAxes, 'on')
                        
                        % loop through each element and add the line that
                        % goes down to the xy plane
                        for x = 1:size(extractedData,1)
                            plot3(app.GraphAxes, [extractedData{x,1} extractedData{x,1}], [extractedData{x,2} extractedData{x,2}], [0 extractedData{x,3}], 'k--')
                        end
                        
                        % Stop appending to the same axis
                        hold (app.GraphAxes, 'off')
                        
                        % Add the graph labels based on the table col name
                        % values
                        xlabel(app.GraphAxes, extractedData.Properties.VariableNames(1));
                        ylabel(app.GraphAxes, extractedData.Properties.VariableNames(2));
                        zlabel(app.GraphAxes, extractedData.Properties.VariableNames(3));
                        
                        % if debug mode is on, then plot the numbers next
                        % each point for easier identifcation
                        if app.ToggledebugCheckBox.Value == 1
                            text(app.GraphAxes, extractedData{:,1},extractedData{:,2}, extractedData{:,3}, " " + string(logicArry))
                        end
                    
                    % Plots a 4D graph when the number of columns in
                    % extracted data = 4, using color as the 4th dimension 
                    elseif size(extractedData,2) == 4
                        
                        % 4D scatter graph where the colour is the 4th
                        % dimenson
                        scatter3(app.GraphAxes, extractedData{:,1}, extractedData{:,2}, extractedData{:,3}, app.dotSize, extractedData{:,4}, "filled")
                        
                        % Displays the gird so the vales are easier to read
                        grid(app.GraphAxes, "on");
                        
                        % Set the colur scheme to autumn
                        cmap = colormap(app.GraphAxes,app.colourMap);
                        % Add the colour bar
                        cbar = colorbar(app.GraphAxes);
                        
                        % This sets up the colours for the dotted lines so
                        % that they match the scattered point colours. If
                        % all the values in extracted method are the same,
                        % then set it to the first color. If if is a
                        % difference between the values, use the max and
                        % min values to generate c, which is a matrix that
                        % spaces out the colours uniformly and in realtion
                        % to the colours of the scatter points.
                        if nnz(diff(extractedData{:,4})) == 0
                            c = ones(size(cmap,1));
                        else
                            c = round(1+(size(cmap,1)-1)*(extractedData{:,4} - min(extractedData{:,4}))/(max(extractedData{:,4})-min(extractedData{:,4})));
                        end
                        
                        % Add to the same axis the lines that go down to
                        % the xy plane
                        hold (app.GraphAxes, 'on');
                        
                        % Based on the index/ color psotion (c) plots a
                        % dotted line that goes down from the point to the
                        % xy plane
                        for x = 1:size(extractedData,1)
                            plot3(app.GraphAxes, [extractedData{x,1} extractedData{x,1}], [extractedData{x,2} extractedData{x,2}], [0 extractedData{x,3}], "Color",cmap(c(x),:), "LineStyle","--")
                        end
                        
                        % Stop appending to the same axis
                        hold (app.GraphAxes, 'off');
                        
                        % Add the graph labels based on the table col name
                        % values
                        xlabel(app.GraphAxes, extractedData.Properties.VariableNames(1));
                        ylabel(app.GraphAxes, extractedData.Properties.VariableNames(2));
                        zlabel(app.GraphAxes, extractedData.Properties.VariableNames(3));
                        
                        % Add title to colour bar
                        title(cbar, extractedData.Properties.VariableNames(4), "FontSize",10, "FontWeight","bold")
                        % Puts in a title so that the spacing at the top of
                        % the graph includes the title above the cbar
                        title(app.GraphAxes, " ", "FontSize",20, "FontWeight","bold")
                        cbar.FontSize = 10; %Makes font of cbar increments larger
                        
                        % if debug mode is on, then plot the numbers next
                        % each point for easier identifcation
                        if app.ToggledebugCheckBox.Value == 1
                            
                            % Loops through each point and adds text next
                            % to it that includes the number and var4 data
                            for graph = 1:size(extractedData,1)
                                text(app.GraphAxes, extractedData{graph,1},extractedData{graph,2}, extractedData{graph,3}, " " + string(logicArry(graph)) + ", " + string(extractedData{graph,4}))
                            end

                        end
                    
                    % Plots a 5D graph when the number of columns in
                    % extracted data = 5, using color as the 4th 
                    % dimension and size of the dot as the 5th dimension.
                    elseif size(extractedData,2) == 5
                        % Change these to vary the maximum and minimim dot
                        % sizes
                        maxDotSize = 100;
                        minDotSize = 5;
                        
                        % Scale the data so that the size is bewteen the
                        % max and min dot size, for when it is plotted on
                        % the graph.
                        scaledData = rescale(extractedData{:,5}, minDotSize, maxDotSize);
                        
                        % Generates 5 uniformly spaced marker sizes between
                        % the min and max dot size specified
                        ledgMarkerSize = linspace(minDotSize, maxDotSize, 5);
                        markersActualNumb = rescale(ledgMarkerSize, min(extractedData{:,5}), max(extractedData{:,5}));
                        
                        % Add to the same axis the lines that go down to
                        % the xy plane
                        hold (app.GraphAxes, 'on');
                        
                        % The loop starts to create the figger that
                        % displays the dot size. It works by plotting 5
                        % points of varying marker size (plot3 has to be
                        % used instead of scatter3 as only this changes the
                        % dot size in the legend for some reason) and
                        % setting their visabilty to 0. Then each each
                        % number that corrisponds to the 5 uniformly
                        % distrubuted sizes is set to a cell whoch then
                        % later is used to called the legend function
                        % (which only shows these first 5 dots). This has
                        % to be done before the scatter function as it
                        % needs to be the FIRST 5 dots.
                        for ind = 1:numel(ledgMarkerSize)
                           % Generate the plots and save them in figplots 
                           % array. Set them at (0,0,0) and set the marker
                           % size to the square root of the ledgMarkerSize
                           % (for some reason this works well for size
                           % comparson, not sure how acurate it is).
                           figplots(ind) = plot3(app.GraphAxes,0,0,0,'bo','markersize',round(sqrt(ledgMarkerSize(ind)),1),'MarkerFaceColor','blue', "MarkerEdgeColor","blue");
                           set(figplots(ind),'visible','off') % Make them invisable
                           
                           % Adds each number (acutal number not the marker
                           % size number) so that it is displayed as the
                           % label when the legend function is called later
                           legentry{ind} = num2str(round(markersActualNumb(ind),1));
                        end
                        
                        % 5D scatter graph where the colour is the 4th
                        % dimenson and size of the dot is the 5th dimension
                        scatter3(app.GraphAxes, extractedData{:,1}, extractedData{:,2}, extractedData{:,3}, scaledData, extractedData{:,4}, "filled")
                        
                        % Stop appending to the same axis
                        hold (app.GraphAxes, 'off');
                        
                        % Displays the gird so the vales are easier to read
                        grid(app.GraphAxes, "on")
                        
                        % Set the colur scheme to autumn
                        cmap = colormap(app.GraphAxes,app.colourMap);
                        
                        % Add the colour bar
                        cbar = colorbar(app.GraphAxes);
                        
                        % This sets up the colours for the dotted lines so
                        % that they match the scattered point colours. If
                        % all the values in extracted method are the same,
                        % then set it to the first color. If if is a
                        % difference between the values, use the max and
                        % min values to generate c, which is a matrix that
                        % spaces out the colours uniformly and in realtion
                        % to the colours of the scatter points.
                        if nnz(diff(extractedData{:,4})) == 0
                            c = ones(size(cmap,1));
                        else
                            c = round(1+(size(cmap,1)-1)*(extractedData{:,4} - min(extractedData{:,4}))/(max(extractedData{:,4})-min(extractedData{:,4})));
                        end

                        % Add to the same axis the lines that go down to
                        % the xy plane
                        hold (app.GraphAxes, 'on');
                        
                        % Based on the index/ color psotion (c) plots a
                        % dotted line that goes down from the point to the
                        % xy plane
                        for x = 1:size(extractedData,1)
                            plot3(app.GraphAxes, [extractedData{x,1} extractedData{x,1}], [extractedData{x,2} extractedData{x,2}], [0 extractedData{x,3}], "Color",cmap(c(x),:), "LineStyle","--")
                        end
                        
                        % Stop appending to the same axis
                        hold (app.GraphAxes, 'off');
                        
                        % Add the graph labels based on the table col name
                        % values
                        xlabel(app.GraphAxes, extractedData.Properties.VariableNames(1));
                        ylabel(app.GraphAxes, extractedData.Properties.VariableNames(2));
                        zlabel(app.GraphAxes, extractedData.Properties.VariableNames(3));
                        
                        % Add title to colour bar
                        title(cbar, extractedData.Properties.VariableNames(4), "FontSize",10, "FontWeight","bold")
                        
                        % Puts in a title so that the spacing at the top of
                        % the graph includes the title above the cbar
                        title(app.GraphAxes, " ", "FontSize",20, "FontWeight","bold")
                        cbar.FontSize = 10; %Makes font of cbar increments larger
                        
                        % Creat the legend at the end after all points have
                        % been added as we only want the first 5 points
                        % (the dummy invisable ones stored in figplots)
                        leg = legend(app.GraphAxes, legentry);
                        leg.Location = 'eastoutside'; %Display next to the colour bar
                        leg.FontSize = 10; % Make font the same size as the color bar
                        % Gives the legend a title
                        title(leg, extractedData.Properties.VariableNames(5),"FontSize",10, "FontWeight","bold");
                        
                        %For some reason these actions set the graph state
                        %to 2D, so make sure it is 3D.
                        view(app.GraphAxes,3)

                        % if debug mode is on, then plot the numbers next
                        % each point for easier identifcation
                        if app.ToggledebugCheckBox.Value == 1
                            
                            % Loops through each point and adds text next
                            % to it that includes the number, var4 and 
                            % var5 data data
                            for graph = 1:size(extractedData,1)
                                text(app.GraphAxes, extractedData{graph,1},extractedData{graph,2}, extractedData{graph,3}, " " + string(logicArry(graph)) + ", " + string(extractedData{graph,4}) + ", " + string(extractedData{graph,5}))
                            end
                        end
                    end
                end
            end  
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  

        function [usedVariables, usedResults, LB, UB, tableForm, disData] = checkVariables(app)
            % Check if the reponce function values are not all the
            % same, and exclude any from the opt if they are (if/when
            % it is then vaired the opt will change to include it)
            
            % Looks at the objective function results from the prevous
            % experiments. If there are differences in the data (the
            % responce function HAS been changing when the external
            % factors have been changed) accept it and store it in used
            % results. If not exculde it as it will produce and error
            % if it is fed into the opt algorithm
            usedResults = [];
            table1 = [];
            if ~isempty(app.AllResults)
            
                objFuncDiff = zeros(1,size(cell2mat(app.AllResults{:,:}),2));
                for x = 1:size(cell2mat(app.AllResults{:,:}),2)
                    if length(unique(cell2mat(app.AllResults{:,x}))) > 1
                        objFuncDiff(x) = 1;
                    else
                        objFuncDiff(x) = 0;
                    end
                end
                
                % Finds the algorithms that have been ACTUALLY selected to
                % prevent them from all being input into the algorithm
                for x = 1:length(string(app.respSelected))
                    indResp(x) = find(strcmp(app.responceFunctions{1, 1}, string(app.respSelected(x))));
                end
                selectedAlgs = ones(1,length(indResp));
                
                %Combine those that are changing AND those that have been
                %selected to give the actual opt algorithm data to pass
                objFuncDiff = logical(objFuncDiff) & selectedAlgs;
                usedResults = app.AllResults(:,objFuncDiff);
                table1 = usedResults;
                usedResults = cell2mat(usedResults{:,:});
            end
            
            % Looks at the variables from the prevous
            % experiments. is not If there are differences in the data (the
            % variables have changed) accept it and store it in used
            % variables. If not exculde it as it a condtion that
            % is being varied.
            variablesDiff = ~all(app.allConditions{:,:} == app.allConditions{1,:});
            usedVariables = app.allConditions(:,variablesDiff);
            table2 = usedVariables;
            usedVariables = usedVariables{:,:};
            
            %Convert the discrete variables (if any) into ordered numbers
            %instead of the set numbers. E.g. if discrete values 2 and 4
            %are used, turn these into 1 and 2 to give to the algorithm and
            %then change back after so that the algorithm works.
            
            
            % Finds the index of the discrete data being optimised (if
            % there is any)
            index = find((app.variables.DiscreteOrContinuous == 'D') & (app.variables.Optimise == 1))';
            levels = []; % predefines the levels of the data
            
            % if the index is not empty (therefore there are discrete
            % variables being optimised)
            if ~isempty(index)
                  
                % Loop through each index and add the length (the number of
                % different levels) of the discrete array to 'levels'.
                for ind = 1:length(index)
                    levels = [levels, (cellfun(@(x) size(x, 2), app.variables.Data(index(ind))))];
                end
                
                % Changes the index so that it is the last 'however many'
                % variables in the data set that are descrete
                newindex = size(usedVariables,2) - (length(index) - 1) :size(usedVariables,2);
                % Combine this index data and levels info into one array to
                % return this data
                disData = [newindex;levels];
                
                % Loop through each discrete variable
                for x = 1:size(app.variables.Data(index),1)
                    % The differnt possible values for that discrete var
                    discretVars = app.variables.Data{index(x)};
                    % Numbers 1 to the number of seleted discrete
                    % factors that will replace the vars above
                    replaceNumbs = 1:length(app.variables.Data{index(x)});
                    
                    % Loops through each possible value in the discrete var
                    % and replaces it with the new ordered list.
                    for disVar = 1:length(app.variables.Data{index(x)})
                        % Obtains the logical array to find all the
                        % elements that contain the numbers being replaced.
                        % Then uses this array to replace the number with
                        % the new orderd list before moving onto the next
                        % one. E.g. if 3 5 is being replaced with 1 2, the
                        % first iteration will find the index where all the
                        % 3's are and then the second line will replace
                        % them with 1. Then next loop will then find the
                        % location of all the 5's and replace them with 2.
                        logicArry = (usedVariables(:,newindex(x)) == discretVars(disVar));
                        usedVariables(logicArry,newindex(x)) = replaceNumbs(disVar);
                    end
                end
                
            else
                % If empty then return an empty matrix
                disData = [];
            end
            
            % This section of code extracts the upper and lower boundary
            % for each continous variable looked at.
            contLog = (app.variables.DiscreteOrContinuous == 'C') & (app.variables.Optimise == 1);
            contUBLB = cell2mat(app.variables.Data(contLog));
            
            % If the data contains discrete variables, this section sets
            % the upper and lower bounds sutiable for the algorithm. So if
            % discrete vars 2,4 and 5 are selected the LB is set
            % to 1 and the UB is set too 3 (as there are 3 dis differences
            % in total).
            if ~isempty(disData)
                
                disUBLB = []; % predefine
                % Loop through each discrete var in the disData matrix 
                % defined ealier
                for x = 1:size(disData,2)
                    % Create the new upper and lower bound based on the max
                    % number of levels detected. (the second row is the
                    % number of levels/different discrete var options).
                    disUBLB = [disUBLB; 1 disData(2,x)];
                end
                
                % Concatinate both the continous and discrete data to be
                % returned
                allUBLB = [contUBLB;disUBLB];
            else
                % If no discrete vars, then just return the 
                allUBLB = contUBLB;
            end
            
            LB = allUBLB(:,1)'; % Extracts the LB data
            UB = allUBLB(:,2)'; % Extracts the UB data 
            tableForm = [table2 table1];
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        % Writes the experiment to the text box given the experiments to
        % write. exps = [1:app.currExp] if you want all of them or you
        % could set exps = [2 5 8 etc.] for any experiment you want.

        function writeCurrExperiment(app, exps, log)
            if nargin == 2
                log = false;
            end
            
            % Concatinates the name of the headers for the inital condtions 
            % and the responce factors selected.
            headers =  [app.allConditions.Properties.VariableNames app.AllResults.Properties.VariableNames];
            % Generates a numbered list from 1 to the number of experiments
            % to inform the user which experiment belongs to which.
            % 'Exp.' is the heading of this column.
            experiments = [{'Exp.'} cellstr(string(exps))];
            % Convert the results and condtions into the correct format cell 
            % matrix of characters: this is the format it needs to be in 
            % for the 'print_table' function. "'UniformOutput', false" is 
            % needed to do this.
            resultsTemp = cellfun(@string, table2cell(app.AllResults), 'UniformOutput', false);
            condtionsTemp = cellfun(@string, table2cell(app.allConditions(exps,:)), 'UniformOutput', false);
            
            % This loop adds 'N/A' to the results matrix if the experiment
            % has not been run yet. This is only needed if the current 
            % experiment number is less than the number of inital 
            % experiments generated.

            % Predefine temp empty cell
            newResults = cell(length(exps), length(app.AllResults.Properties.VariableNames));
            % Loops through each inital result
            for x = exps
                % If the current results have been generated (which
                % would be contained in 'resultsTemp') then try to add
                % these condtions to the cell. If it can't add it (it
                % does not exist), 'catch' will replace it with
                % 'N/A' noting the experiment has not been run yet.
                try 
                    newResults(x,:) = resultsTemp(x,:);
                    
                    % if cell row is actully empty then replace it with
                    % 'N/A'. Just checks the first row because if one
                    % is empty, all of them will be.
                    if isempty(resultsTemp{x,1})
                        newResults(x,:) = {'N/A'};
                    end
                catch
                    newResults(x,:) = {'N/A'};
                end
            end
            
            % Updates results with the extracted ones. If selected cells is
            % used there will be gaps in the table. This code removes these
            % empty cell rows.
            resultsTemp = newResults(~cellfun('isempty', newResults(:,1)), :);
            
            % Generates the neatly formated table for display
            tableText = newline + string(print_table([condtionsTemp resultsTemp] , {'%s'}, headers, experiments','printBorder',1));
            % Updates the textbox with the formated data
            app.updateTextBox(true, tableText, log);
        end


        function saveSettings(app)
            
            % Saves all the important infomation into a settings folder so
            % that it can be loaded next time.

            app.Settings.fileNameSave = app.FileSaveLocatonEditField.Value;
            app.fileNameSave = app.FileSaveLocatonEditField.Value;

            app.Settings.WaitMethodFolderLocation = app.WaitMethodFolderLocationEditField.Value;
            app.WaitMethodFolderLocation = app.WaitMethodFolderLocationEditField.Value;

            app.Settings.MethodTemplateFolderLocation = app.MethodTemplateFolderLocationEditField.Value;
            app.MethodTemplateFolderLocation = app.MethodTemplateFolderLocationEditField.Value;

            app.Settings.MethodTemplateName = app.MethodTemplateNameEditField.Value;
            app.MethodTemplateName = app.MethodTemplateNameEditField.Value;

            app.Settings.WaitMethodTemplateName = app.WaitMethodTemplateNameEditField.Value;
            app.WaitMethodTemplateName = app.WaitMethodTemplateNameEditField.Value;

            app.Settings.ResultsFolderLocation = app.ResultsFolderLocationEditField.Value;
            app.ResultsFolderLocation = app.ResultsFolderLocationEditField.Value;

            app.Settings.ResultsFileName = app.ResultsFileNameEditField.Value;
            app.ResultsFileName = app.ResultsFileNameEditField.Value;
          
            app.Settings.initExpNumb = app.InitexpSpinner.Value;
            app.initExpNumb = app.InitexpSpinner.Value;

            app.Settings.equalTime = app.EqulibrationtimeminSpinner.Value;
            app.equalTime = app.EqulibrationtimeminSpinner.Value;

            app.Settings.algSelected = app.AlgorithmDropDown.Value;
            app.algSelected = app.AlgorithmDropDown.Value;

            app.Settings.respSelected = app.ObjectivefunctionsListBox.Value;
            app.respSelected = app.ObjectivefunctionsListBox.Value;

            app.Settings.samplePos = app.SamplePositionEditField.Value;
            app.samplePos = app.SamplePositionEditField.Value;

            app.Settings.ampCutoff = app.AmplituecutoffEditField.Value;
            app.ampCutoff = app.AmplituecutoffEditField.Value;

            app.Settings.areaCutoff = app.AreacutoffEditField.Value;
            app.areaCutoff = app.AreacutoffEditField.Value;

            app.Settings.totExpNumb = app.OptexpSpinner.Value;
            app.totExpNumb = app.OptexpSpinner.Value;

            app.Settings.varFileName = app.OptimsationfilenameEditField.Value;
            app.varFileName  = app.OptimsationfilenameEditField.Value;

            app.Settings.inVol = app.InjectionvolumeLEditField.Value;
            app.injVol = app.InjectionvolumeLEditField.Value;

            app.Settings.finalHoldTime = app.FinalholdtimeminEditField.Value;
            app.finalHoldTime = app.FinalholdtimeminEditField.Value;

            app.Settings.voidTimeCutOff = app.VoidtimecutoffEditField.Value;
            app.voidTimeCutOff = app.VoidtimecutoffEditField.Value;
            
            app.Settings.ampThresh = app.AmpthreshEditField.Value;
            app.ampThresh = app.AmpthreshEditField.Value;

            app.Settings.slopeThresh = app.SlopethreshEditField.Value;
            app.slopeThresh = app.SlopethreshEditField.Value;

            app.Settings.peakGroup = app.PeakgroupEditField.Value;
            app.peakGroup = app.PeakgroupEditField.Value;

            app.Settings.smoothWidth = app.SmoothwidthEditField.Value;
            app.smoothWidth = app.SmoothwidthEditField.Value;

            app.Settings.paramFileName = app.ParamfilenameEditField.Value;
            app.paramFileName = app.ParamfilenameEditField.Value;
            
            
        end

        function loadSettings(app, vars)
            
            % Try loading the Settings file, if it does not exist then just
            % keep the defaults.
            try
                app.Settings = vars{8,2};

                try 
                    
                    app.fileNameSave = app.Settings.fileNameSave;
                    app.FileSaveLocatonEditField.Value = app.Settings.fileNameSave;
                    
                    app.WaitMethodFolderLocation = app.Settings.WaitMethodFolderLocation;
                    app.WaitMethodFolderLocationEditField.Value = app.Settings.WaitMethodFolderLocation;

                    app.MethodTemplateFolderLocation = app.Settings.MethodTemplateFolderLocation;
                    app.MethodTemplateFolderLocationEditField.Value = app.Settings.MethodTemplateFolderLocation;

                    app.MethodTemplateName = app.Settings.MethodTemplateName;
                    app.MethodTemplateNameEditField.Value = app.Settings.MethodTemplateName;

                    app.WaitMethodTemplateName = app.Settings.WaitMethodTemplateName;
                    app.WaitMethodTemplateNameEditField.Value = app.Settings.WaitMethodTemplateName;

                    app.ResultsFolderLocation = app.Settings.ResultsFolderLocation;
                    app.ResultsFolderLocationEditField.Value = app.Settings.ResultsFolderLocation;

                    app.ResultsFileName = app.Settings.ResultsFileName;
                    app.ResultsFileNameEditField.Value = app.Settings.ResultsFileName;

                    app.initExpNumb = app.Settings.initExpNumb;
                    app.InitexpSpinner.Value = app.Settings.initExpNumb;

                    app.equalTime = app.Settings.equalTime;
                    app.EqulibrationtimeminSpinner.Value = app.Settings.equalTime;

                    app.algSelected = app.Settings.algSelected;
                    app.AlgorithmDropDown.Value = app.Settings.algSelected;

                    app.respSelected = app.Settings.respSelected;
                    app.ObjectivefunctionsListBox.Value = app.Settings.respSelected;

                    app.samplePos = app.Settings.samplePos;
                    app.SamplePositionEditField.Value = app.Settings.samplePos;

                    app.ampCutoff = app.Settings.ampCutoff;
                    app.AmplituecutoffEditField.Value = app.Settings.ampCutoff;

                    app.areaCutoff = app.Settings.areaCutoff;
                    app.AreacutoffEditField.Value = app.Settings.areaCutoff;

                    app.totExpNumb = app.Settings.totExpNumb;
                    app.OptexpSpinner.Value = app.Settings.totExpNumb;

                    app.varFileName = app.Settings.varFileName;
                    app.OptimsationfilenameEditField.Value = app.Settings.varFileName;
                    
                    app.injVol = app.Settings.inVol;
                    app.InjectionvolumeLEditField.Value = app.Settings.inVol;
                    
                    app.finalHoldTime = app.Settings.finalHoldTime;
                    app.FinalholdtimeminEditField.Value = app.Settings.finalHoldTime;

                    app.voidTimeCutOff = app.Settings.voidTimeCutOff;
                    app.VoidtimecutoffEditField.Value = app.Settings.voidTimeCutOff;

                    app.ampThresh = app.Settings.ampThresh;
                    app.AmpthreshEditField.Value = app.Settings.ampThresh;

                    app.slopeThresh = app.Settings.slopeThresh;
                    app.SlopethreshEditField.Value = app.Settings.slopeThresh;

                    app.peakGroup = app.Settings.peakGroup;
                    app.PeakgroupEditField.Value = app.Settings.peakGroup;

                    app.smoothWidth = app.Settings.smoothWidth;
                    app.SmoothwidthEditField.Value = app.Settings.smoothWidth;

                    app.paramFileName = app.Settings.paramFileName;
                    app.ParamfilenameEditField.Value = app.Settings.paramFileName;

                catch
                     app.updateTextBox(true, newline + "WARNING. One or more settings failed to load.")
                end

            catch

                app.updateTextBox(true, newline + "Failed to load settings. Saving default conditions as new ones.")
                app.saveSettings();

            end
            
        end
        
        function generateMethods(app)
            for x = 1:app.totExpNumb
                FNTemp = app.MethodTemplateFolderLocation + "\" + app.MethodTemplateName;
                FNMeth = app.fileNameSave + "\" + app.varFileName  + "\" + app.MethodsFileName + "\" + app.varFileName + app.methodName + string(x);

                % Copy files from template
                copyfile(FNTemp + ".acm", FNMeth + ".acm");
                copyfile(FNTemp + ".w2200", FNMeth + ".w2200");
                copyfile(FNTemp + ".ccm", FNMeth + ".ccm");
                copyfile(FNTemp + ".bgm", FNMeth + ".bgm");
                copyfile(FNTemp + ".wpda", FNMeth + ".wpda");
                copyfile(FNTemp + ".exp", FNMeth + ".exp");

                if x <= size(app.allConditions{:, 1}, 1)

                    % Generate the time table
                    timeTable = generateTimeTable(app.allConditions{x, :}, str2double(app.finalHoldTime));
                    methTime = timeTable{end,1};
                    temp = app.allConditions{x, 3};
                    pressure = app.allConditions{x, 5};
                
                    % Modfiy the created method files
                    ColTempChange(FNMeth + ".acm", temp, methTime, app.equalTime);
                    ColSwitchChange(FNMeth + ".w2200", methTime, app.equalTime);
                    PressureChange(FNMeth + ".ccm", methTime, pressure);
                    PumpChange(FNMeth + ".bgm", timeTable);
                    DetectorChange(FNMeth + ".wpda", methTime);
                    massSpecFileChange(FNMeth + ".exp", methTime);

                end

                app.updateTextBox(true, "Method" + x + " generated." + newline)
                pause(0.2)
            end

        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Set the different algoithms and reference to their function
            % handles
            app.algorithms{1,:} = ["TS-EMO", "BayesianOptimiser"];
            app.algorithms{2,:} = {@TSEMO_V4Cond, @BayesianOpt};
            app.algorithms{3,:} = {@TSEMO_options, @optionsBayesOpt};
            
            % Set the responce functions and reference to their function
            % handles, and the data transform for when plotting the graphs
            app.responceFunctions{1,:} = ["RsCrit2", "Maximisepeaknumber", "Minimisemethodtime", "RsCrit2NoLog", "MaximisepeaknumerNoLog", "MinimisemethodtimeNoLog"];
            app.responceFunctions{2,:} = {@responceRscrit3, @responceNumbPeaks, @responceMaxTime, @responceRscritNoLog, @responceNumbPeaksNoLog, @responceMaxTimeNoLog};
            app.responceFunctions{3,:} = ["negNatLog", "negNatLog", "natLog", "neg", "neg", "none"];
            
            % Updates the responce function drop down so that it contains
            % the names stated above
            respNames = app.responceFunctions{1,:}(:);
            app.ObjectivefunctionsListBox.Items = respNames;
            app.AlgorithmDropDown.Items = app.algorithms{1,:}(:);
            app.AlgorithmDropDown.Value = app.algorithms{1,:}(1);
            
            % Updates the alg names so that the drop down contains the
            % names stated above
            algNames = app.algorithms{1,:}(:);
            app.AlgorithmDropDown.Items = algNames;
            app.AlgorithmDropDown.Value = algNames(1); 
            app.updateRespBox();
            
            %Colour Blind Friendly colourmap
            app.wongCM = [230,159,0;        %Orange
                          86, 180, 233;     %Blue
                          0,158,115;        %Green
                          240,228,66;       %Yellow
                          0,114,178;        %Dark blue
                          213,94,0;         %Dark orange
                          204,121,167;      %Pink
                          0,0,0]./255;      %Black

            app.colourMapsAll = ["parula", "turbo", "hsv", "hot", "cool", "spring", "summer", "autumn", "winter", "gray", "bone", "copper", "pink", "jet"];
            app.colourMap = "parula";
            app.ColourbarDropDown.Items = app.colourMapsAll;
            app.ColourbarDropDown.Value = "parula";
            app.dotSize = 20;
            app.DotsizeSlider.Value = app.dotSize;

        end

        % Button pushed function: ApplyVarsButton
        function CreateNewExp(app, event)
            
            %Wipe the text box
            app.updateTextBox(false, "")
            
            % Looks at the file name entered and adds the '.mat'
            % CHANGE FOLDER SAVE IN FUTURE TO CONTAIN WHOLE DIRC NAME!
            app.folderSave = app.FileSaveLocatonEditField.Value + "\" + app.OptimsationfilenameEditField.Value;
            app.fileNameSave = app.OptimsationfilenameEditField.Value + ".mat";
            filename = app.folderSave + "\" + app.fileNameSave;
            
            % if the file already exists, promts the user with a pop up box
            % to notify them that they will be overwriting a file if they
            % proceeed with this.
            if isfolder(app.folderSave)
                % Code for the pop up box to warn the user that it will change
                % the responce function values. If no is selected nothing
                % happens. Look below for yes.
                selection = uiconfirm(app.HPLC, "Are you sure you want to " + ...
                    "apply these new settings? This will overwrite the current file." + ...
                    "functions.", "Reprocess", "Icon","warning","Options", ...
                    ["Yes", "No"], "DefaultOption", 2, "CancelOption",2);
                
                % if selection is yes, then overwrite the file
                if selection == "Yes"
                    app.applyVars()
                end
            
            % If it does not already exist, then create it anyway
            else
                mkdir(app.folderSave);
                app.applyVars()
            end
        end

        % Button pushed function: StartInitButton
        function StartInitButtonPushed(app, event)
            
            app.updateTextBox(true, newline + "***OPTIMISATION STARTED***" + newline)
            
            app.InitLamp.Color = [0 1 0];
            app.paused = false;
            app.optimisationLoop = true;
            app.waitForResults = false;
                
            while app.optimisationLoop

                % Set the current experiment
                if isempty(app.AllResults{1,1}{1,1})
                    app.currExp = 1;
                else
                    app.currExp = size(app.AllResults,1) + 1;
                end

                % If the inital condtions are still running, no new results
                % need to be written so continue just waiting for the next
                % epxeriment.
                if app.currExp <= app.initExpNumb 
                
                    app.updateTextBox(true, newline + "***Inital condtions running***" + newline)
                   
                % If experiments are queued up then run these first
                elseif length(app.AllResults{:,1}) ~= length(app.allConditions{:,1})

                    app.updateTextBox(true, newline + "***Running queued experiment***" + newline)

                % If there are no experiments lined up then run through the
                % optimsation algorithm
                else
                    app.updateTextBox(true, newline + "***Running optimisation experiment***" + newline)

                    % Returns the variables that are being changed (the ones
                    % being optimised), the results of the objective functions
                    % (only returns an objective function if the value of the
                    % function is being varied), as well as the upper and lower
                    % bounds of each continous variable used. This data is fed
                    % into the optimisation algorithm.
                    try
                        [usedVariables, usedResults, LB, UB, ~, disData] = app.checkVariables();
                    catch
                        app.updateTextBox(true, newline + "Error processing variables, stopping optimisation." + newline)
                        app.logTrack.error('StartOptButtonPushed', 'Error processing variables for optimisation.')
                        return
                    end
                    
                    try

                        pause(1)
                
                        % Find the index of the algorithm selected so that it can
                        % point to its function handle
                        
                        ind = find(contains(app.algorithms{1,:}, app.algSelected));
                        funcHandle = app.algorithms{2,:}{ind};
                        optHandle = app.algorithms{3,:}{ind};
                        
                        % Runs the code that sets the options for the optimisation
                        % algorithn selected. (These options can be changed in
                        % their respective code files)
                        options = feval(optHandle);
                        
                        % Run the opt algorithm given the optimisation function has
                        % the following structure: func(optimiseFunc, Xvars, Yvars,
                        % LB, UB, options). Stores all the output into a cell
                        % array. 
                        X = feval(funcHandle, usedVariables, usedResults, LB, UB, options);
            
                        app.updateTextBox(true, newline + "New conditions generated!" + newline, true)

                        % If more than one experiment is returned, add them
                        % all to the list
                        if size(X,1) > 1
                            app.updateTextBox(true, newline + string(size(X,1)) + " new experiments have been reccomended. " + ...
                                "Adding each of these experiments to the list, which will run in the following iterations." + newline, true)
                        end

                    catch % If variables failed to process.
                        app.updateTextBox(true, newline + "Algorithm failed to evaluate next set of condtions, stopping optimisation." + newline)
                        app.logTrack.error('StartOptButtonPushed', 'Algorithm failed to evaluate next set of condtions, stopping optimisation.')
                        return
                    end

                    pause(1)
                    
                    % Based on X, add this data to the inital experiments
                    % table.
                    currVar = 1;
                    curExp = app.currExp;
                    
                    % Loops through each experiment (if there is more than
                    % 1) and adds each new one
                    for exp = 1:size(X,1)
                        % Loops through each variable]
                        for var = 1:length(app.variables.Optimise)
                            % If the vairable is being optimised add it to the
                            % row and column in init condtions
                            if app.variables.Optimise(var) == 1
                                app.allConditions{curExp, var} = X(exp,currVar);
                                currVar = currVar + 1; % Add one to the current variable
                            else
                                % If the variable is continous
                                if app.variables.DiscreteOrContinuous(var) == "C"
                                    % Add it as normal as it is a number already
                                    app.allConditions{curExp, var} = app.variables.Data{var};
                                % if the variable is discrete    
                                elseif app.variables.DiscreteOrContinuous(var) == "D"
                                    % Convert it to a number first and then add it.
                                    app.allConditions{curExp, var} = str2double(app.variables.Data{var});
                                end
                            end
                        end
                        curExp = curExp + 1; % Add one to the current experiments
                        currVar = 1;
                    end

                    % Now modify the method file with the new condtions so
                    % that it is updated before it is run

                    FNMeth = app.fileNameSave + "\" + app.varFileName  + "\" + app.MethodsFileName + "\" + app.varFileName + app.methodName + string(x);
     
                    % Rewrite file with specific method condtions
                    % Generate the time table
                    timeTable = generateTimeTable(app.allConditions{app.currExp, :}, str2double(app.finalHoldTime));
                    methTime = timeTable{end,1};
                    temp = app.allConditions{app.currExp, 3};
                    pressure = app.allConditions{app.currExp, 5};
                
                    % Modfiy the created method files
                    ColTempChange(FNMeth + ".acm", temp, methTime, app.equalTime);
                    ColSwitchChange(FNMeth + ".w2200", methTime, app.equalTime);
                    PressureChange(FNMeth + ".ccm", methTime, pressure);
                    PumpChange(FNMeth + ".bgm", timeTable);
                    DetectorChange(FNMeth + ".wpda", methTime);
                    massSpecFileChange(FNMeth + ".exp", methTime);

                end

                % Notfify the user the experiment has started using the
                % clock
                c = clock;
                time = string(c(4)) + ":" + string(c(5)); % Converts the time to the correct format
                app.updateTextBox(true, "Experiment " + string(app.currExp) + " started at " + time + newline, true)
                app.updateTextBox(true, "Condtions: " + newline, true)
            
                % Write the current conditions that are to be run.
                app.writeCurrExperiment(app.currExp, true)

                app.waitForResults = true;

                app.updateTextBox(true, "Waiting for method to finish..." + newline + newline, true)

                % Save the data to the file so if the condtion gets lost it
                % will be run when app is rebooted as a queued run.
                app.saveVarFile();


                % Wait for method to finish, then read and process results
                while app.waitForResults
                
                    % Open up data file location where methods are stored and create string
                    % list
                    fileData = dir(app.ResultsFolderLocation);
                    fileNames = cellfun(@string, {fileData.name});
                    
                    % Find generated file. If it does not exist then wait 5 seconds and try
                    % again
                    matchFile = fileNames == app.varFileName +  "WaitMethod" + string(app.currExp) + ".raw";

                    if nnz(matchFile)>0                
                        
                        % Attempt to calculate the responce variables from the
                        % results. Reprocesses all the results including
                        % the newest experiment run.
                        try
                            app.updateTextBox(true, "Calculating responce variables ..." + newline + newline, true)
                            app.CalcResponces(true); 
                            app.updateTextBox(true, "Responce vars generated. Experiment " + string(app.currExp) + " finished. Results: " + newline, true)
                            
                        catch % If this fails notfiy the user and terminate the function
                            
                            app.updateTextBox(true, newline + "Failed to calculate responce variables" + newline)
                            app.logTrack.error('StartInitButtonPushed', 'Failed to calculate responce variables.')
                            return
                        end

                        % Update the graph box with the new data
                        app.UpdateGraphList();

                        % Save the data to the file before running the next 
                        app.saveVarFile();
                        
                        % Write the updated condtions with the responces
                        app.writeCurrExperiment(app.currExp, true)

                        % Stop the waiting for results loop and run the
                        % next experiment
                        app.waitForResults = false;

                    end


                    % Wait a few seconds so that the script is not always
                    % running super fast.
                    pause(5)

                    % If the pause button is pressed
                    if app.optimisationLoop == false
                        break
                    end

                end
            end
            
            % Display time when the condtions finished
            c = clock;
            time = string(c(4)) + ":" + string(c(5));
            app.updateTextBox(true, "***OPTIMSATION STOPED AT " + time + "***", true)
            
            % Reconfiger buttons
            app.InitLamp.Color = [1 0 0];


        end

        % Value changed function: HPLCListBox
        function HPLCListBoxValueChanged(app, event)
            app.updateGraph()
        end

        % Button pushed function: LoadVarsButton
        function LoadVarsButtonPushed(app, event)
            [filename, currPath] = uigetfile('*.mat','Select a file to load', app.ResultsDir);
            
            % Creates the directory of where the new folder save will be
            app.folderSave = string(currPath(1:end-1));
            app.fileNameSave = filename;
            
            if filename == 0
                app.updateTextBox(false, "No file was selected.")
            else
                try
                    app.updateTextBox(true, "Loading data ...")

                    %Load the data file based on the name inputed into the text
                    %box. This is read as a structure. Convert the structure to a
                    %cell and then access the first element of this cell to 'open'
                    %it properly. (May be a better way to do this).
                    vars = load([currPath filename]);
                    vars = struct2cell(vars);
                    vars = vars{1,1};
                    
                    % Based on the contents of the file, set all the variables
                    
                    % Variables table
                    app.variables = vars{1,2};
                    
                    % Then overwrite the 'variables.mat' file with the updated
                    % conditions
                    vars2save = vars{1,2};
                    save("Variables.mat", "vars2save")
                    
                    % The actual condtions based on the LHS
                    app.allConditions = vars{2,2};
                    % The results of each inital conditon
                    app.AllResults = vars{3,2};

                    % Set the prevous graph and peaks data recorded
                    app.graphPeaksData = vars{6,2};
                    % Load the algorithm output
                    app.algOutput = vars{7,2};

                    % Load the settings - handles if settings have not been
                    % set before
                    app.loadSettings(vars);
                   
                    % If the first element of graph peak data is empty (is its a
                    % new save that has not had any experiments run yet), set the
                    % current experiment to 1. Else set it to the lenght of the
                    % list + 1 so that it is ready to run the next experiment.
                    if isempty(app.graphPeaksData{1,1})
                        app.currExp = 1;
                    else
                        app.currExp = size(app.graphPeaksData,1) + 1;
                    end
        
                    % Updates the graph list box
                    app.UpdateGraphList()
                    
                    % Set up the Var list box with all the variables
                    app.allVars = [app.variables.Name' app.respSelected];
                    app.VarListBox.Items = app.allVars;
                    
                    % Then display the data in the text box
                    app.DisplayData()
                    
                    app.InitLamp.Color = [1 0 0]; %Turn inital condtions lamp to red (not running)
                    
                    % Create/load log file
                    app.logTrack = log4m.getLogger(app.folderSave + "\" + app.logFileName);
                    
                    app.logTrack.debug('LoadVarsButtonPushed', 'File successfuly loaded.')
                catch
                    app.resetUI();
                    app.updateTextBox(false, "File failed to load.")
                end
            end
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.resetUI(); % Resets the UI back to the default
        end

        % Button pushed function: ConfigvarButton
        function ConfigvarButtonPushed(app, event)
            % Open the options dialog and pass inputs
            app.DialogApp = ConfigVars(app);
        end

        % Value changed function: AlgorithmDropDown
        function AlgorithmDropDownValueChanged(app, event)
            app.updateRespBox();
        end

        % Button pushed function: PauseInitButton
        function PauseInitButtonPushed(app, event)
            app.optimisationLoop = false;
            app.waitForResults = false;
            app.updateTextBox(true, newline + "Stopping optimsation" + newline)
        end

        % Value changed function: ToggledebugCheckBox
        function ToggledebugCheckBoxValueChanged(app, event)
            app.updateGraph();
        end

        % Value changed function: Switchgraphstate
        function SwitchgraphstateValueChanged(app, event)
            app.updateGraph();
        end

        % Value changed function: VarListBox
        function VarListBoxValueChanged(app, event)
            app.updateGraph();     
        end

        % Button pushed function: FilesavelocationButton
        function FilesavelocationButtonPushed(app, event)
            app.FileSaveLocatonEditField.Value = uigetdir();
        end

        % Button pushed function: ReprocessfilesButton
        function ReprocessfilesButtonPushed(app, event)
            % Recaculate responses
            app.CalcResponces(false)

            % Update the graph box with the new data
            app.UpdateGraphList();

            % Save the data to the file before running the next 
            app.saveVarFile();
            
            % Write the updated condtions with the responces
            app.writeCurrExperiment(1:length(app.allConditions{:,1}), true)
        end

        % Button pushed function: WaitMethodnameButton
        function WaitMethodnameButtonPushed(app, event)
            app.WaitMethodTemplateNameEditField.Value = uigetdir();
        end

        % Button pushed function: MethodtemplatenameButton
        function MethodtemplatenameButtonPushed(app, event)
            app.MethodTemplateNameEditField.Value = uigetdir();
        end

        % Button pushed function: GeneratemethodfilesButton
        function GeneratemethodfilesButtonPushed(app, event)
            
            %Copy a new method fiel for each experiment planned to run.
            %Then edit the methods files based on the inital condtions data
            %set. The rest will just be the template but these will be
            %overwritten during the optimisation.

            app.updateTextBox(true, "Method generating started, this may take some time!" + newline)
     
            app.generateMethods()

            app.updateTextBox(true, "Method generating finished." + newline, true)
        end

        % Button pushed function: SavechangesButton
        function SavechangesButtonPushed(app, event)
            app.saveSettings()
            app.saveVarFile()
        end

        % Value changed function: ColourbarDropDown
        function ColourbarDropDownValueChanged(app, event)
            app.colourMap = app.ColourbarDropDown.Value;
            app.updateGraph()
            
        end

        % Value changed function: DotsizeSlider
        function DotsizeSliderValueChanged(app, event)
            app.dotSize = app.DotsizeSlider.Value;
            app.updateGraph()
            
        end

        % Value changed function: DataTransformCheckBox
        function DataTransformCheckBoxValueChanged(app, event)
            app.updateGraph()
            
        end

        % Button pushed function: ExportDatatocsvButton
        function ExportDatatocsvButtonPushed(app, event)
   

            if isempty(app.AllResults{:,:}{1,1})
                results = array2table(NaN(1,length(app.AllResults.Properties.VariableNames)),'VariableNames',app.AllResults.Properties.VariableNames);
            else
                %Convert results into the correct format for the table
                results =  array2table(cellfun(@double, app.AllResults{:,:}), "VariableNames", app.AllResults.Properties.VariableNames);
            end

            %If there are less results than there are experiments
            %suggested, set the values to NaN
            if size(results,1) < size(app.allConditions,1)
                results{size(results,1)+1:size(app.allConditions,1),:} = NaN;
            end

            % concatonate results and then save to file
            data2Write = [app.allConditions results];
            writetable(data2Write, app.folderSave + "\ExportedData.csv")
        end

        % Button pushed function: MethodtemplatefolderlocationButton
        function MethodtemplatefolderlocationButtonPushed(app, event)
            app.MethodTemplateFolderLocationEditField.Value = uigetdir();
        end

        % Button pushed function: WaitmethodfolderlocationButton
        function WaitmethodfolderlocationButtonPushed(app, event)
            app.WaitMethodFolderLocationEditField.Value = uigetdir();
        end

        % Button pushed function: ResultsfolderlocationButton
        function ResultsfolderlocationButtonPushed(app, event)
            app.ResultsFolderLocationEditField.Value = uigetdir();
        end

        % Button pushed function: ResultsfilenameButton
        function ResultsfilenameButtonPushed(app, event)
            app.ResultsFileNameEditField.Value = uigetdir();
        end

        % Button pushed function: CreateworklistcsvButton
        function CreateworklistcsvButtonPushed(app, event)
            FileNameSave = app.varFileName + "Method";
            expNumbers = (1:app.totExpNumb)';
            expNumbers = sort([expNumbers;expNumbers]);

            FileNameSave = FileNameSave + expNumbers;
            waitMethodNames = app.varFileName + "WaitMethod" + expNumbers;
            FileNameSave(2:2:end) = "WaitMethod";

            FileNameSaveStart = FileNameSave;
            FileNameSaveStart(2:2:end) = waitMethodNames(2:2:end);

            BottleLoc = FileNameSave;
            BottleLoc(:) = app.samplePos;

            injVolStr = FileNameSave;
            injVolStr(:) = "0";
            injVolStr(1:2:end) = app.injVol;

            paramFile = FileNameSave;
            paramFile(:) = app.paramFileName;
            paramFile(2:2:end) = "";

            processFile = FileNameSave;
            processFile(:) = "OpenLynx";
            processFile(2:2:end) = "";
    
            headersTables = ["File Name", "File Text", "MS File", "Inlet File", "Bottle", "Inject volume", "Parameter file", "Process"];
            tableData = [FileNameSaveStart, FileNameSaveStart, FileNameSave, FileNameSave, BottleLoc, injVolStr, paramFile, processFile];

            seqTable = array2table(tableData, "VariableNames",headersTables);
            writetable(seqTable, app.folderSave + "\SequenceTable.csv");


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HPLC = uifigure('Visible', 'off');
            app.HPLC.Name = 'HPLC Opt';
            app.TabGroup = uitabgroup(app.HPLC);
            app.DefineinputsTab = uitab(app.TabGroup);
            app.DefineinputsTab.Title = 'Define inputs';
            app.Step1Label = uilabel(app.DefineinputsTab);
            app.Step1Label.Text = 'Step 1: Define parameters and folder locations';
            app.FileSaveLocatonEditField = uieditfield(app.DefineinputsTab, 'text');
            app.FileSaveLocatonEditField.Tooltip = {'Where you want the data from this optimsation to be stored.'};
            app.ApplyVarsButton = uibutton(app.DefineinputsTab, 'push');
            app.ApplyVarsButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewExp, true);
            app.ApplyVarsButton.Text = 'Create new experiment';
            app.LoadVarsButton = uibutton(app.DefineinputsTab, 'push');
            app.LoadVarsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVarsButtonPushed, true);
            app.LoadVarsButton.Text = 'Load prevous experiment';
            app.ResetButton = uibutton(app.DefineinputsTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ConfigvarButton = uibutton(app.DefineinputsTab, 'push');
            app.ConfigvarButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigvarButtonPushed, true);
            app.ConfigvarButton.Text = 'Configure input variables';
            app.FilesavelocationButton = uibutton(app.DefineinputsTab, 'push');
            app.FilesavelocationButton.ButtonPushedFcn = createCallbackFcn(app, @FilesavelocationButtonPushed, true);
            app.FilesavelocationButton.Text = 'File save location';
            app.NumberofinitalexperimentsSpinnerLabel = uilabel(app.DefineinputsTab);
            app.NumberofinitalexperimentsSpinnerLabel.Text = 'Number of inital experiments';
            app.InitexpSpinner = uispinner(app.DefineinputsTab);
            app.InitexpSpinner.Limits = [0 100];
            app.InitexpSpinner.Value = 10;
            app.ObjectivefunctionsListBoxLabel = uilabel(app.DefineinputsTab);
            app.ObjectivefunctionsListBoxLabel.Text = 'Objective functions';
            app.ObjectivefunctionsListBox = uilistbox(app.DefineinputsTab);
            app.ObjectivefunctionsListBox.Multiselect = 'on';
            app.ObjectivefunctionsListBox.Value = {'Item 1'};
            app.AlgorithmDropDownLabel = uilabel(app.DefineinputsTab);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.DefineinputsTab);
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
            app.EqulibrationtimeminSpinnerLabel = uilabel(app.DefineinputsTab);
            app.EqulibrationtimeminSpinnerLabel.Text = 'Equlibration time / min';
            app.EqulibrationtimeminSpinner = uispinner(app.DefineinputsTab);
            app.EqulibrationtimeminSpinner.Value = 20;
            app.OptimsationfilenameEditFieldLabel = uilabel(app.DefineinputsTab);
            app.OptimsationfilenameEditFieldLabel.Text = 'Optimsation file name';
            app.OptimsationfilenameEditField = uieditfield(app.DefineinputsTab, 'text');
            app.SamplePositionEditFieldLabel = uilabel(app.DefineinputsTab);
            app.SamplePositionEditFieldLabel.Text = 'Sample Position';
            app.SamplePositionEditField = uieditfield(app.DefineinputsTab, 'text');
            app.SamplePositionEditField.Value = 'P1-A1';
            app.InfomationTextArea = uitextarea(app.DefineinputsTab);
            app.InfomationTextArea.Editable = 'off';
            app.Step2Label = uilabel(app.DefineinputsTab);
            app.Step2Label.Text = 'Step 3: Start and run optimsation';
            app.StartInitButton = uibutton(app.DefineinputsTab, 'push');
            app.StartInitButton.ButtonPushedFcn = createCallbackFcn(app, @StartInitButtonPushed, true);
            app.StartInitButton.Text = 'Start';
            app.InitLamp = uilamp(app.DefineinputsTab);
            app.PauseInitButton = uibutton(app.DefineinputsTab, 'push');
            app.PauseInitButton.ButtonPushedFcn = createCallbackFcn(app, @PauseInitButtonPushed, true);
            app.PauseInitButton.Text = 'Pause';
            app.NumberofexperimentsSpinnerLabel = uilabel(app.DefineinputsTab);
            app.NumberofexperimentsSpinnerLabel.Text = 'Number of experiments';
            app.OptexpSpinner = uispinner(app.DefineinputsTab);
            app.OptexpSpinner.Limits = [0 100];
            app.OptexpSpinner.Value = 30;
            app.ReprocessfilesButton = uibutton(app.DefineinputsTab, 'push');
            app.ReprocessfilesButton.ButtonPushedFcn = createCallbackFcn(app, @ReprocessfilesButtonPushed, true);
            app.ReprocessfilesButton.Text = 'Reprocess files';
            app.AmplituecutoffLabel = uilabel(app.DefineinputsTab);
            app.AmplituecutoffLabel.Text = 'Amplitue cut off';
            app.AmplituecutoffEditField = uieditfield(app.DefineinputsTab, 'numeric');
            app.AmplituecutoffEditField.Value = 15;
            app.Step1Label_2 = uilabel(app.DefineinputsTab);
            app.Step1Label_2.Text = 'Step 2: Generate method files and work list';
            app.GeneratemethodfilesButton = uibutton(app.DefineinputsTab, 'push');
            app.GeneratemethodfilesButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratemethodfilesButtonPushed, true);
            app.GeneratemethodfilesButton.Text = 'Generate method files';
            app.AreacutoffLabel = uilabel(app.DefineinputsTab);
            app.AreacutoffLabel.Text = 'Area cut off';
            app.AreacutoffEditField = uieditfield(app.DefineinputsTab, 'numeric');
            app.AreacutoffEditField.Value = 15;
            app.StepsLabel = uilabel(app.DefineinputsTab);
            app.StepsLabel.Text = {'1) Click start here first'; '2) Load the generated worklist on masshunter and start it'};
            app.MethodTemplateNameEditField = uieditfield(app.DefineinputsTab, 'text');
            app.MethodTemplateNameEditField.Tooltip = {'The name of the template method (in the folder specified above). Do not add extension.'};
            app.MethodtemplatenameButton = uibutton(app.DefineinputsTab, 'push');
            app.MethodtemplatenameButton.ButtonPushedFcn = createCallbackFcn(app, @MethodtemplatenameButtonPushed, true);
            app.MethodtemplatenameButton.Text = 'Method template name';
            app.WaitMethodTemplateNameEditField = uieditfield(app.DefineinputsTab, 'text');
            app.WaitMethodTemplateNameEditField.Tooltip = {'The name of the wait method (in the folder location above).Do not add extension'};
            app.WaitMethodnameButton = uibutton(app.DefineinputsTab, 'push');
            app.WaitMethodnameButton.ButtonPushedFcn = createCallbackFcn(app, @WaitMethodnameButtonPushed, true);
            app.WaitMethodnameButton.Text = 'Wait Method name';
            app.SavechangesButton = uibutton(app.DefineinputsTab, 'push');
            app.SavechangesButton.ButtonPushedFcn = createCallbackFcn(app, @SavechangesButtonPushed, true);
            app.SavechangesButton.Text = 'Save changes';
            app.InjectionvolumeLEditFieldLabel = uilabel(app.DefineinputsTab);
            app.InjectionvolumeLEditFieldLabel.Text = 'Injection volume /µL ';
            app.InjectionvolumeLEditField = uieditfield(app.DefineinputsTab, 'text');
            app.InjectionvolumeLEditField.Value = '2';
            app.FinalholdtimeminEditFieldLabel = uilabel(app.DefineinputsTab);
            app.FinalholdtimeminEditFieldLabel.Text = 'Final hold time / min';
            app.FinalholdtimeminEditField = uieditfield(app.DefineinputsTab, 'text');
            app.FinalholdtimeminEditField.Value = '5';
            app.Typically2n1wherennumberofvarsbeingoptimisedLabel = uilabel(app.DefineinputsTab);
            app.Typically2n1wherennumberofvarsbeingoptimisedLabel.Text = 'Typically 2n + 1 where n = number of vars being optimised';
            app.VoidtimecuttoffminutesLabel = uilabel(app.DefineinputsTab);
            app.VoidtimecuttoffminutesLabel.Text = 'Void time cut off / minutes';
            app.VoidtimecutoffEditField = uieditfield(app.DefineinputsTab, 'numeric');
            app.VoidtimecutoffEditField.Value = 0.7;
            app.ExportDatatocsvButton = uibutton(app.DefineinputsTab, 'push');
            app.ExportDatatocsvButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDatatocsvButtonPushed, true);
            app.ExportDatatocsvButton.Text = 'Export Data to csv';
            app.ResultsFolderLocationEditField = uieditfield(app.DefineinputsTab, 'text');
            app.ResultsFolderLocationEditField.Tooltip = {'The location where the results files are stored (.raw). Used to read when the Wait method has started.'};
            app.ResultsfolderlocationButton = uibutton(app.DefineinputsTab, 'push');
            app.ResultsfolderlocationButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsfolderlocationButtonPushed, true);
            app.ResultsfolderlocationButton.Text = 'Results folder location';
            app.ResultsFileNameEditField = uieditfield(app.DefineinputsTab, 'text');
            app.ResultsFileNameEditField.Tooltip = {'Location and name of the .rpt file that saves the data after each iteration.'};
            app.ResultsfilenameButton = uibutton(app.DefineinputsTab, 'push');
            app.ResultsfilenameButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsfilenameButtonPushed, true);
            app.ResultsfilenameButton.Text = 'Results file name';
            app.MethodTemplateFolderLocationEditField = uieditfield(app.DefineinputsTab, 'text');
            app.MethodTemplateFolderLocationEditField.Tooltip = {'The folder location of the method template you want to use'};
            app.MethodtemplatefolderlocationButton = uibutton(app.DefineinputsTab, 'push');
            app.MethodtemplatefolderlocationButton.ButtonPushedFcn = createCallbackFcn(app, @MethodtemplatefolderlocationButtonPushed, true);
            app.MethodtemplatefolderlocationButton.Text = 'Method template folder location';
            app.WaitMethodFolderLocationEditField = uieditfield(app.DefineinputsTab, 'text');
            app.WaitMethodFolderLocationEditField.Tooltip = {'The folder location of the wait method you want to use'};
            app.WaitmethodfolderlocationButton = uibutton(app.DefineinputsTab, 'push');
            app.WaitmethodfolderlocationButton.ButtonPushedFcn = createCallbackFcn(app, @WaitmethodfolderlocationButtonPushed, true);
            app.WaitmethodfolderlocationButton.Text = 'Wait method folder location';
            app.CreateworklistcsvButton = uibutton(app.DefineinputsTab, 'push');
            app.CreateworklistcsvButton.ButtonPushedFcn = createCallbackFcn(app, @CreateworklistcsvButtonPushed, true);
            app.CreateworklistcsvButton.Text = 'Create worklist csv';
            app.AmpthreshEditFieldLabel = uilabel(app.DefineinputsTab);
            app.AmpthreshEditFieldLabel.Text = 'Amp thresh';
            app.AmpthreshEditField = uieditfield(app.DefineinputsTab, 'numeric');
            app.SmoothwidthEditFieldLabel = uilabel(app.DefineinputsTab);
            app.SmoothwidthEditFieldLabel.Text = 'Smooth width';
            app.SmoothwidthEditField = uieditfield(app.DefineinputsTab, 'numeric');
            app.SlopethreshEditFieldLabel = uilabel(app.DefineinputsTab);
            app.SlopethreshEditFieldLabel.Text = 'Slope thresh';
            app.SlopethreshEditField = uieditfield(app.DefineinputsTab, 'numeric');
            app.PeakgroupEditFieldLabel = uilabel(app.DefineinputsTab);
            app.PeakgroupEditFieldLabel.Text = 'Peak group';
            app.PeakgroupEditField = uieditfield(app.DefineinputsTab, 'numeric');
            app.ParamfilenameEditFieldLabel = uilabel(app.DefineinputsTab);
            app.ParamfilenameEditFieldLabel.Text = 'Param file name';
            app.ParamfilenameEditField = uieditfield(app.DefineinputsTab, 'text');
            app.ParamfilenameEditField.Value = 'x.olp';
            app.DatavisulisationTab = uitab(app.TabGroup);
            app.DatavisulisationTab.Title = 'Data visulisation';
            app.GraphAxes = uiaxes(app.DatavisulisationTab);
            title(app.GraphAxes, 'HPLC')
            xlabel(app.GraphAxes, 'Time / min')
            ylabel(app.GraphAxes, 'Intensity')
            app.HPLCListBox = uilistbox(app.DatavisulisationTab);
            app.HPLCListBox.Items = {};
            app.HPLCListBox.Multiselect = 'on';
            app.HPLCListBox.ValueChangedFcn = createCallbackFcn(app, @HPLCListBoxValueChanged, true);
            app.HPLCListBox.Value = {};
            app.Switchgraphstate = uiswitch(app.DatavisulisationTab, 'slider');
            app.Switchgraphstate.Items = {'1', '2'};
            app.Switchgraphstate.ValueChangedFcn = createCallbackFcn(app, @SwitchgraphstateValueChanged, true);
            app.Switchgraphstate.Value = '1';
            app.ToggledebugCheckBox = uicheckbox(app.DatavisulisationTab);
            app.ToggledebugCheckBox.ValueChangedFcn = createCallbackFcn(app, @ToggledebugCheckBoxValueChanged, true);
            app.ToggledebugCheckBox.Text = 'Toggle debug mode';
            app.VarListBox = uilistbox(app.DatavisulisationTab);
            app.VarListBox.Items = {};
            app.VarListBox.Multiselect = 'on';
            app.VarListBox.ValueChangedFcn = createCallbackFcn(app, @VarListBoxValueChanged, true);
            app.VarListBox.Value = {};
            app.YUpperSpinnerLabel = uilabel(app.DatavisulisationTab);
            app.YUpperSpinnerLabel.Text = 'Y Upper';
            app.YUpperSpinner = uispinner(app.DatavisulisationTab);
            app.YUpperSpinner.Value = 200;
            app.YlowerSpinnerLabel = uilabel(app.DatavisulisationTab);
            app.YlowerSpinnerLabel.Text = 'Y lower';
            app.YLowerSpinner = uispinner(app.DatavisulisationTab);
            app.YLowerSpinner.Value = -5;
            app.TimemaxSpinnerLabel = uilabel(app.DatavisulisationTab);
            app.TimemaxSpinnerLabel.Text = 'Time max';
            app.TimemaxSpinner = uispinner(app.DatavisulisationTab);
            app.TimemaxSpinner.Value = 7;
            app.ColourbarLabel = uilabel(app.DatavisulisationTab);
            app.ColourbarLabel.Text = 'Colour bar';
            app.ColourbarDropDown = uidropdown(app.DatavisulisationTab);
            app.ColourbarDropDown.ValueChangedFcn = createCallbackFcn(app, @ColourbarDropDownValueChanged, true);
            app.DotsizeSliderLabel = uilabel(app.DatavisulisationTab);
            app.DotsizeSliderLabel.Text = 'Dot size';
            app.DotsizeSlider = uislider(app.DatavisulisationTab);
            app.DotsizeSlider.Limits = [1 100];
            app.DotsizeSlider.ValueChangedFcn = createCallbackFcn(app, @DotsizeSliderValueChanged, true);
            app.DotsizeSlider.Value = 20;
            app.DataTransformCheckBox = uicheckbox(app.DatavisulisationTab);
            app.DataTransformCheckBox.ValueChangedFcn = createCallbackFcn(app, @DataTransformCheckBoxValueChanged, true);
            app.DataTransformCheckBox.Text = 'Data Transform';
            % Show the figure after all components are created
            app.HPLC.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HPLCapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HPLC)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HPLC)
        end
    end
end